classdef basic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PlotControlsTab               matlab.ui.container.Tab
        WhatareweplottingButtonGroup  matlab.ui.container.ButtonGroup
        SquaresButton                 matlab.ui.control.RadioButton
        NeedlesButton                 matlab.ui.control.RadioButton
        BestagonsButton               matlab.ui.control.RadioButton
        NeedleCountSpinnerLabel       matlab.ui.control.Label
        NeedleCountSpinner            matlab.ui.control.Spinner
        UIControlsTab                 matlab.ui.container.Tab
        c1931370Label                 matlab.ui.control.Label
        OutEstimateLabel              matlab.ui.control.Label
        PlottingStatusLampLabel       matlab.ui.control.Label
        PlottingStatusLamp            matlab.ui.control.Lamp
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
		S	=	1					% Scale factor
        N	=	1000				% Number of needles
		NoP	=	10					% Number of planks
		D							% Distance between planks
		SL							% Length of square side
		
		% Patch object
		p
		
		% State
		sq_angles
		xc
		yc
		xcr
		ycr
    end
    
    methods (Access = private)
        
		function updatePlankCount(app, plankCount)
			app.NoP = plankCount;
			app.D =	app.S / app.NoP;
			
			updatePlot(app);
		end
        
		function updateSquareCount(app, squareCount)
			app.N = squareCount;
			app.sq_angles = rand(1, app.N) * 360;
			app.xc = rand(1, app.N);
			app.yc = rand(1, app.N);
			
			updateSquarePlot(app);
		end
		
        function updateSquarePlot(app)
			updatePlotFloor(app);
			
			delete(app.p);
			
			app.xcr = [...
				app.SL * cosd(app.sq_angles) + app.SL * sind(app.sq_angles) + app.xc;...
				app.SL * cosd(app.sq_angles) - app.SL * sind(app.sq_angles) + app.xc;...
				-app.SL * cosd(app.sq_angles) - app.SL * sind(app.sq_angles) + app.xc;...
				-app.SL * cosd(app.sq_angles) + app.SL * sind(app.sq_angles) + app.xc;...
			];
			app.ycr = [...
				-app.SL * sind(app.sq_angles) + app.SL * cosd(app.sq_angles) + app.yc;...
				-app.SL * sind(app.sq_angles) - app.SL * cosd(app.sq_angles) + app.yc;...
				app.SL * sind(app.sq_angles) - app.SL * cosd(app.sq_angles) + app.yc;...
				app.SL * sind(app.sq_angles) + app.SL * cosd(app.sq_angles) + app.yc;...
			];
			
			calculateSquarePi(app);
			
			% Plot all the things
			app.p = patch(app.UIAxes, app.xcr, app.ycr, 'red');
		end
		
		function calculateSquarePi(app)
			n = 0;
			n = n + sum(floor(app.xcr(1, :)/app.D) ~= floor(app.xcr(2, :)/app.D)) + ...
					sum(floor(app.xcr(2, :)/app.D) ~= floor(app.xcr(3, :)/app.D)) + ...
					sum(floor(app.xcr(3, :)/app.D) ~= floor(app.xcr(4, :)/app.D)) + ...
					sum(floor(app.xcr(4, :)/app.D) ~= floor(app.xcr(1, :)/app.D));
			t = app.N*4;
			
			pi_estimate = t/n;
			UIUpdateOutEstimate(app, ['Pi Estimate: ' num2str(pi_estimate)]);
		end
		
		function UIUpdateOutEstimate(app, value)
			set(app.OutEstimateLabel, 'Text', value);
		end
		
		function updatePlotFloor(app)
			for i = 0:app.D:app.S
				xline(app.UIAxes, i,  '-', 'LineWidth', 2)
			end
		end
	end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.D	=	app.S / app.NoP;
			app.SL	=	app.D / 4;
        end

        % Value changed function: NeedleCountSpinner
        function NeedleCountSpinnerValueChanged(app, event)
            value = ceil(app.NeedleCountSpinner.Value);
            updateSquareCount(app, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotControlsTab = uitab(app.TabGroup);
            app.PlotControlsTab.Title = 'Plot Controls';
            app.WhatareweplottingButtonGroup = uibuttongroup(app.PlotControlsTab);
            app.WhatareweplottingButtonGroup.Title = 'What are we plotting?';
            app.SquaresButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.SquaresButton.Text = 'Squares';
            app.SquaresButton.Value = true;
            app.NeedlesButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.NeedlesButton.Text = 'Needles';
            app.BestagonsButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.BestagonsButton.Text = 'Bestagons';
            app.NeedleCountSpinnerLabel = uilabel(app.PlotControlsTab);
            app.NeedleCountSpinnerLabel.Text = 'Needle Count';
            app.NeedleCountSpinner = uispinner(app.PlotControlsTab);
            app.NeedleCountSpinner.Limits = [0 10000000];
            app.NeedleCountSpinner.ValueChangedFcn = createCallbackFcn(app, @NeedleCountSpinnerValueChanged, true);
            app.NeedleCountSpinner.Value = 1000;
            app.UIControlsTab = uitab(app.TabGroup);
            app.UIControlsTab.Title = 'UI Controls';
            app.c1931370Label = uilabel(app.UIFigure);
            app.c1931370Label.Text = 'c1931370';
            app.OutEstimateLabel = uilabel(app.UIFigure);
            app.OutEstimateLabel.Text = 'Pi Estimate: N/A';
            app.PlottingStatusLampLabel = uilabel(app.UIFigure);
            app.PlottingStatusLampLabel.Text = 'Plotting Status';
            app.PlottingStatusLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Floor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end